classdef MainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        TabGroup     matlab.ui.container.TabGroup
        Tab          matlab.ui.container.Tab
        Panel_2      matlab.ui.container.Panel
        IGauge       matlab.ui.control.LinearGauge
        AGaugeLabel  matlab.ui.control.Label
        Knob         matlab.ui.control.Knob
        Panel        matlab.ui.container.Panel
        Lamp         matlab.ui.control.Lamp
        Label_3      matlab.ui.control.Label
        MainSwitch   matlab.ui.control.Switch
        Label_2      matlab.ui.control.Label
        Tab_2        matlab.ui.container.Tab
        KLabel       matlab.ui.control.Label
        EditField    matlab.ui.control.EditField
        Label        matlab.ui.control.Label
        TheoPlot     matlab.ui.control.Button
        ExpPlot      matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
        Tab_3        matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %设置图窗参数
            axis(app.UIAxes,[800,2500,0,6000]);%坐标范围
            grid(app.UIAxes,"on");%网格
            title(app.UIAxes,"");%图像标题
            xlabel(app.UIAxes,"波长\lambda(nm)");%横坐标名
            ylabel(app.UIAxes,"辐射密度E(\lambda,T)(W/mm^3)");%纵坐标名
        end

        % Button pushed function: ExpPlot
        function ExpPlotPushed(app, event)
            if strcmp(app.MainSwitch.Value,'Off')
                msgbox("实验仪器电源未打开，请到控制区将仪器开关置于On","non-modal");
            else
                %理论曲线
                value = app.EditField.Value;%输入温度

                %实验数据文件名
                File="Data\"+["E2270K.txt" "E2330K.txt" "E2360K.txt" ...
                    "E2400K.txt" "E2440K.txt" "E2500K.txt" "E2580K.txt"...
                    "E2660K.txt" "E2750K.txt" "E2860K.txt" "E2940K.txt"];
                %寻找输入温度的实验数据
                for i=1:11
                    s1="Data\E"+value+"K.txt";
                    s2=File(i);
                    if strcmp(s1,s2)==1
                        file_n=i;
                    end
                end
                %载入第ffile_n个实验数据
                Lab_Data=load(File(file_n));
                lambda=Lab_Data(:,1);%横坐标
                E_e=Lab_Data(:,2);%纵坐标

                %动态实验曲线绘制
                cla(app.UIAxes);%清理曲线

                for j=2:length(lambda)
                    hold(app.UIAxes,"on");
                    i=j-1;
                    plot(app.UIAxes,lambda(i:j),E_e(i:j),'-g');
                    pause(0.0001);
                end
            end
        end

        % Button pushed function: TheoPlot
        function TheoPlotButtonPushed(app, event)
            if strcmp(app.MainSwitch.Value,'Off')
                msgbox("实验仪器电源未打开，请到控制区将仪器开关置于On","non-modal");
            else
                %理论曲线
                value = app.EditField.Value;%输入温度
                T=str2double(value);%将输入温度的形式从字符转化为数值

                %物理常数
                h=6.62607015*10^-34;
                c=299792458;
                k=1.380649*10^-23;
                %经验参数
                c1=2*pi*h*c^2;
                c2=h*c/k;

                %自变量
                lambda=800:1:2500;%单位nm
                Lambda=lambda*10^-9;%单位m
                %计算辐射密度理论曲线
                E_t=(c1./power(Lambda,5)).*(1./(exp(c2./(Lambda.*T))-1))*10^-9;

                %绘图
                plot(app.UIAxes,lambda,E_t,'-r');
            end
        end

        % Value changed function: MainSwitch
        function MainSwitchValueChanged(app, event)
            value = app.MainSwitch.Value;
            if strcmp(value,'Off')%当总开关关闭时指示灯不亮
                app.Lamp.Color=[0 0 0];
            else%当总开关打开时指示灯亮起
                app.Lamp.Color=[0 1 0];
            end
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            if strcmp(app.MainSwitch.Value,'Off')
                msgbox("实验仪器电源未打开，请到控制区将仪器开关置于On","non-modal");
            else
                value = app.Knob.Value;
                app.IGauge.Value=value;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '控制区';
            app.Panel = uipanel(app.Tab);
            app.Panel.Title = '黑体辐射实验仪电源';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '仪器开关';
            app.MainSwitch = uiswitch(app.Panel, 'slider');
            app.MainSwitch.ValueChangedFcn = createCallbackFcn(app, @MainSwitchValueChanged, true);
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '电源指示灯';
            app.Lamp = uilamp(app.Panel);
            app.Panel_2 = uipanel(app.Tab);
            app.Panel_2.Title = '溴钨灯电源';
            app.Knob = uiknob(app.Panel_2, 'continuous');
            app.Knob.Limits = [1 11];
            app.Knob.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11];
            app.Knob.MajorTickLabels = {'1.4', '1.5', '1.6', '1.7', '1.8', '1.9', '2.0', '2.1', '2.2', '2.3', '2.5'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.MinorTicks = [];
            app.Knob.Value = 1;
            app.AGaugeLabel = uilabel(app.Panel_2);
            app.AGaugeLabel.Text = '电流(A)';
            app.IGauge = uigauge(app.Panel_2, 'linear');
            app.IGauge.Limits = [1 11];
            app.IGauge.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11];
            app.IGauge.MajorTickLabels = {'1.4', '1.5', '1.6', '1.7', '1.8', '1.9', '2.0', '2.1', '2.2', '2.3', '2.5'};
            app.IGauge.MinorTicks = [];
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '测量区';
            app.UIAxes = uiaxes(app.Tab_2);
            zlabel(app.UIAxes, 'Z')
            app.ExpPlot = uibutton(app.Tab_2, 'push');
            app.ExpPlot.ButtonPushedFcn = createCallbackFcn(app, @ExpPlotPushed, true);
            app.ExpPlot.Text = '测量实验曲线';
            app.TheoPlot = uibutton(app.Tab_2, 'push');
            app.TheoPlot.ButtonPushedFcn = createCallbackFcn(app, @TheoPlotButtonPushed, true);
            app.TheoPlot.Text = '理论曲线';
            app.Label = uilabel(app.Tab_2);
            app.Label.Text = '黑体温度';
            app.EditField = uieditfield(app.Tab_2, 'text');
            app.KLabel = uilabel(app.Tab_2);
            app.KLabel.Text = 'K';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '计算区';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end